classdef MikAdAM_V1a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_SetupDAQ                   matlab.ui.container.Tab
        Panel_DAQDeviceSetup            matlab.ui.container.Panel
        Button_FindDAQdevices           matlab.ui.control.Button
        DropDown_Device                 matlab.ui.control.DropDown
        DropDown_DeviceSubSystem        matlab.ui.control.DropDown
        DevicesfoundLampLabel           matlab.ui.control.Label
        Lamp_Devicesfound               matlab.ui.control.Lamp
        DropDown_SampleRate             matlab.ui.control.DropDown
        TextArea_MeasTypes              matlab.ui.control.TextArea
        MeasurementtypesavailableLabel  matlab.ui.control.Label
        Table_ChannelList               matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        Button_ChannelSetup             matlab.ui.control.Button
        EditcolumnLabel                 matlab.ui.control.Label
        EditcolumnLabel_2               matlab.ui.control.Label
        Panel_ChannelSetup              matlab.ui.container.Panel
        Table_SelChannelList            matlab.ui.control.Table
        CheckunitscarefullyifcalibrationappliedLabel  matlab.ui.control.Label
        DurationoftriggeredacquisitionsLabel  matlab.ui.control.Label
        NumBox_Durationofacquisition    matlab.ui.control.NumericEditField
        DurationofpretriggersEditFieldLabel  matlab.ui.control.Label
        NumBox_Durationofpretrigger     matlab.ui.control.NumericEditField
        TriggeringchannelDropDownLabel  matlab.ui.control.Label
        DropDown_Triggeringchannel      matlab.ui.control.DropDown
        DAQSetupSummaryLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TextArea_DAQsetupsummary        matlab.ui.control.TextArea
        NumBox_DurationOfBuffer         matlab.ui.control.NumericEditField
        DurationofrunningbuffersLabel   matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        TextArea_Console                matlab.ui.control.TextArea
        InfoconsoleLabel                matlab.ui.control.Label
        Button_CreateDAQTask            matlab.ui.control.Button
        EditcolumnLabel_3               matlab.ui.control.Label
        EditcolumnLabel_4               matlab.ui.control.Label
        EditcolumnLabel_5               matlab.ui.control.Label
        NumBox_DurationOfBuffer_2       matlab.ui.control.NumericEditField
        PlotrefreshperiodsLabel         matlab.ui.control.Label
        TriggerlevelamplitudeLabel      matlab.ui.control.Label
        NumBox_TriggerLevel             matlab.ui.control.NumericEditField
        InstructionsforStage1Panel      matlab.ui.container.Panel
        GuidetomeasurementtypesavailablePanel  matlab.ui.container.Panel
        TextArea_MeasTypes_2            matlab.ui.control.TextArea
        StepbystepPanel                 matlab.ui.container.Panel
        TextArea_MeasTypes_3            matlab.ui.control.TextArea
        InstructionsforStage2Panel      matlab.ui.container.Panel
        GuidetosensortypesavailablePanel  matlab.ui.container.Panel
        TextArea_MeasTypes_4            matlab.ui.control.TextArea
        StepbystepPanel_2               matlab.ui.container.Panel
        TextArea_MeasTypes_5            matlab.ui.control.TextArea
        Tab2_Measure                    matlab.ui.container.Tab
        Axes_Live                       matlab.ui.control.UIAxes
        Axes_Trig_Ham                   matlab.ui.control.UIAxes
        Axes_Trig_Acc                   matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        Button_StartDAQ                 matlab.ui.control.Button
        Button_StopDAQ                  matlab.ui.control.Button
        PlotControlsPanel               matlab.ui.container.Panel
        Button_TrigIn_ResetPlot         matlab.ui.control.Button
        PanSwitch_2Label                matlab.ui.control.Label
        Switch_TrigIn_Pan               matlab.ui.control.Switch
        ZoomSwitch_2Label               matlab.ui.control.Label
        Switch_TrigIn_Zoom              matlab.ui.control.Switch
        PlotControlsPanel_2             matlab.ui.container.Panel
        Button_TrigOuts_ResetPlot       matlab.ui.control.Button
        PanSwitch_2Label_2              matlab.ui.control.Label
        Switch_TrigOuts_Pan             matlab.ui.control.Switch
        ZoomSwitch_2Label_2             matlab.ui.control.Label
        Switch_TrigOuts_Zoom            matlab.ui.control.Switch
        Axes_FreqMag                    matlab.ui.control.UIAxes
        Axes_FreqPhase                  matlab.ui.control.UIAxes
        PlotControlsPanel_3             matlab.ui.container.Panel
        Button_Mag_ResetPlot            matlab.ui.control.Button
        PanSwitch_2Label_3              matlab.ui.control.Label
        Switch_Mag_Pan                  matlab.ui.control.Switch
        ZoomSwitch_2Label_3             matlab.ui.control.Label
        Switch_Mag_Zoom                 matlab.ui.control.Switch
        flowHzEditFieldLabel            matlab.ui.control.Label
        axLim_Mag_fLow                  matlab.ui.control.NumericEditField
        fhighHzEditFieldLabel           matlab.ui.control.Label
        axLim_Mag_fHigh                 matlab.ui.control.NumericEditField
        dBhighEditFieldLabel            matlab.ui.control.Label
        axLim_Mag_dBHigh                matlab.ui.control.NumericEditField
        dBlowEditFieldLabel             matlab.ui.control.Label
        axLim_Mag_dBLow                 matlab.ui.control.NumericEditField
        PlotControlsPanel_4             matlab.ui.container.Panel
        Button_Phase_ResetPlot          matlab.ui.control.Button
        PanSwitch_2Label_4              matlab.ui.control.Label
        Switch_Phase_Pan                matlab.ui.control.Switch
        ZoomSwitch_2Label_4             matlab.ui.control.Label
        Switch_Phase_Zoom               matlab.ui.control.Switch
        CapturedDataMangementPanel      matlab.ui.container.Panel
        Table_BufferControl             matlab.ui.control.Table
        Button_ClearAllBuffers          matlab.ui.control.Button
        Button_ExportData               matlab.ui.control.Button
        Button_ClearSelectedBuffers     matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        Button_CaptureKeep              matlab.ui.control.Button
        Button_CaptureDiscard           matlab.ui.control.Button
        Axes_FreqCoherence              matlab.ui.control.UIAxes
        PlotDisplayControlPanel         matlab.ui.container.Panel
        Table_PlotSelect                matlab.ui.control.Table
        Button_PlotLastCapture          matlab.ui.control.Button
        Button_PlotEmsemble             matlab.ui.control.Button
        Lamp_LastCaptureOnly            matlab.ui.control.Lamp
        Lamp_BufferAverage              matlab.ui.control.Lamp
        CaptureclippedPanel             matlab.ui.container.Panel
        LampLabel                       matlab.ui.control.Label
        Lamp_ClipWarning                matlab.ui.control.Lamp
        TextArea_ClipWarning            matlab.ui.control.TextArea
        DropDown_LiveChan               matlab.ui.control.DropDown
        LivechanneldisplayLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        DAQ_Task    % Description
        capture     % Description
        handlesPlot % Description
        counters    % Description
        deviceInfo  % Description
        dataRetained  % Description
        dataMean    % Description
    end
    
    methods (Access = private)
        
        function setupDAQ(app)
            %-----------------------------------------------------%
            % The "Create DAQ Task" button has been clicked.
            % This function is called from that button's callback.
            % It does the heavy lifting in setting up the DAQ task
            %-----------------------------------------------------%
            
            % Update user info console
            app.TextArea_Console.Value = {'Setting up DAQ task...'};
            
            % (1) Setup the new DAQ session (will be either 'ni' or 'directaudio')
            DAQ_session_type    = app.deviceInfo.selectedDevice.Vendor.ID;
            app.DAQ_Task        = daq.createSession(DAQ_session_type);
            
            % (2) Identify the selected channels: Pulls out the indices (from the table) of the selected channels
            chanListSel  = find(app.capture.chanSelected_logical);
            
            % (3) Run through selected channels and add them 1 by 1 to the DAQ task, error checking as you go
            for nChan = 1:app.capture.chanCount
                thisCard        = app.deviceInfo.selectedDevice.ID;
                thisChanNumber  = chanListSel(nChan);
                
                % Now add channels according to whether 'ni' or 'directsound' type device
                switch DAQ_session_type
                    case 'ni'
                        thisMeasType_abr = app.Table_SelChannelList.Data{nChan,6};
                        switch thisMeasType_abr
                            case 'Vo'
                                thisMeasType = 'Voltage';
                            case 'Ac'
                                thisMeasType = 'Accelerometer';
                            case 'Mi'
                                thisMeasType = 'Microphone';
                            case 'IE'
                                thisMeasType = 'IEPE';
                        end
                        addAnalogInputChannel(app.DAQ_Task,thisCard,thisChanNumber-1,thisMeasType);
                        
                        % If sensors are IEPE Mics or Accels, allow user to specify sensitivity (and add to DAQ task)
                        %   --> Note: V/XXX required by task (i.e. convert from the mV/XXX supplied by user!)
                        switch thisMeasType
                            case {'Accelerometer';'Microphone'}
                                sensitivity_in_V = str2double(app.Table_SelChannelList.Data{nChan,4})/1000;
                                app.DAQ_Task.Channels(nChan).Sensitivity = sensitivity_in_V;
                        end
                        
                    case 'directsound'
                        addAudioInputChannel(app.DAQ_Task,thisCard,thisChanNumber);
                        thisMeasType = 'Audio';
                    otherwise
                        app.TextArea_Console.Value = {'Incompatible DAQ system used! Only NI hardware and Windows sound cards are supported.'};
                end
                
                % Record the user's custom name + sensor type for the channel (e.g. 'accelerometer 1')
                app.DAQ_Task.Channels(nChan).Name               = app.Table_SelChannelList.Data{nChan,2};
                app.capture.ChannelInfo(nChan).ID               = app.Table_SelChannelList.Data{nChan,1};
                app.capture.ChannelInfo(nChan).Name             = app.Table_SelChannelList.Data{nChan,2};
                app.capture.ChannelInfo(nChan).MeasurementType  = thisMeasType;
                app.capture.ChannelInfo(nChan).SensorType       = char(app.Table_SelChannelList.Data(nChan,3));
                app.capture.ChannelInfo(nChan).FRFType          = [char(app.Table_SelChannelList.Data(nChan,3)) '/f' ];
                app.capture.ChannelInfo(nChan).PlotShow         = true;
            end
            
            
            %-------------------------------------------------------------------------------%
            % (4) Specify global parameters for the DAQ task (e.g. sample rate, duration etc)
            %-------------------------------------------------------------------------------%
            app.capture.Fs              = app.DropDown_SampleRate.Value;
            app.DAQ_Task.Rate           = app.capture.Fs;
            app.capture.T_Capture       = app.NumBox_Durationofacquisition.Value;
            app.capture.T_Buffer        = app.NumBox_DurationOfBuffer.Value;
            app.capture.T_Refresh       = 1;
            app.capture.N_Capture       = round(app.capture.T_Capture*app.capture.Fs);
            app.capture.N_Buffer        = round(app.capture.T_Buffer*app.capture.Fs);
            
            % Set when DAQ buffer flushes samples, and make continuous in background
            app.DAQ_Task.NotifyWhenDataAvailableExceeds = app.capture.T_Refresh*app.capture.Fs;
            app.DAQ_Task.IsContinuous                   = true;
            
            % Time vector for captured signals
            app.capture.timeVec             = [0:app.capture.N_Capture-1]'/app.capture.Fs;
            
            % Frequency axis and DFT calculations
            app.capture.df                      = app.capture.Fs/(2*app.capture.N_Capture); % Double length FFT (avoids time aliasing if using iDFT)
            app.capture.fVec                    = [0:app.capture.df:app.capture.Fs-app.capture.df]';
            app.capture.omegaVec                = 2*pi*app.capture.fVec;
            app.capture.omegaVec_i              = 1i*app.capture.omegaVec;
            
            
            %-------------------------------------------------------------------------------%
            % (5) Take note of requested trigger channel and level
            %-------------------------------------------------------------------------------%
            app.capture.Trig_ChannelID  = app.DropDown_Triggeringchannel.Value;
            app.capture.Trig_Level      = app.NumBox_TriggerLevel.Value;
            app.capture.Trig_T_PreTrig  = app.NumBox_Durationofpretrigger.Value;
            app.capture.Trig_N_PreTrig  = round(app.capture.Fs*app.capture.Trig_T_PreTrig);
            
            switch DAQ_session_type
                case 'ni'
                    app.capture.Trig_Channel = 1+str2double(app.capture.Trig_ChannelID(end));
                case 'directsound'
                    app.capture.Trig_Channel = str2double(app.capture.Trig_ChannelID);
            end
            
            
            %-------------------------------------------------------------------------------%
            % (6) Print back summary of DAQ setup to user in text area
            %-------------------------------------------------------------------------------%
            app.TextArea_DAQsetupsummary.Value = {...
                ['A -' DAQ_session_type '- type DAQ task has been set up on DAQ device: -' app.deviceInfo.selectedDevice.Model '-'];...
                ['Data will be acquired from channels: -' strjoin({app.DAQ_Task.Channels.ID}, ', ') '-'];...
                ['System sample rate is: -' num2str(app.capture.Fs) ' Hz-'];
                ['Total duration of sampled data will be: -' num2str(app.capture.T_Capture) 's- (-' num2str(app.capture.N_Capture) ' samples-)'];...
                ['  --> Of this, -' num2str(app.capture.Trig_T_PreTrig) 's- will be pre-trigger data (-' num2str(app.capture.Trig_N_PreTrig) ' samples-)'];...
                ['Triggering will be when signal is above -' num2str(app.capture.Trig_Level) '- on channel -' app.capture.Trig_ChannelID '-']};
            
            % Finally, tell the user that the system is ready to go
            app.TextArea_Console.Value = {'Success: DAQ system ready to acquire data!';'     --> Go to tab (II) to start DAQ'};
            
            
            %-------------------------------------------------------------------------------%
            % (7) Set up plots/lines
            %-------------------------------------------------------------------------------%
            
            % Set hold off for all axes to allow plots to be reset
            hold(app.Axes_Live,'off');
            hold(app.Axes_Trig_Ham,'off');
            hold(app.Axes_Trig_Acc,'off');
            hold(app.Axes_FreqMag,'off');
            hold(app.Axes_FreqPhase,'off');
            
            % Initialise plot handles
            %app.handlesPlot.pl1         = plot(app.Axes_Live,0,zeros(1,app.capture.chanCount)); % Use to display all signals in 'live' plot
            app.handlesPlot.pl1         = plot(app.Axes_Live,0,0); % Use if only want to display the Hammer signal in 'live' plot
            app.handlesPlot.pl2         = plot(app.Axes_Trig_Ham,0,0);
            app.handlesPlot.pl3         = plot(app.Axes_Trig_Acc,0,zeros(1,app.capture.chanCount-1));
            app.handlesPlot.plPhase     = plot(app.Axes_FreqPhase,0,zeros(1,app.capture.chanCount-1));
            app.handlesPlot.plMag       = plot(app.Axes_FreqMag,0,zeros(1,app.capture.chanCount-1));
            app.handlesPlot.plCoh       = plot(app.Axes_FreqCoherence,0,zeros(1,app.capture.chanCount-1));
            
            %yyaxis(app.Axes_FreqMag,'left')
            %yyaxis(app.Axes_FreqMag,'right')
            
            % Set hold on for all axes (x/y data will be directly updated)
            hold(app.Axes_Live,'on');
            hold(app.Axes_Trig_Ham,'on');
            hold(app.Axes_Trig_Acc,'on');
            hold(app.Axes_FreqMag,'on');
            hold(app.Axes_FreqPhase,'on');
            
            %-------------------------------------------------------------------------------%
            % (8) FRF plot selection table
            %-------------------------------------------------------------------------------%
            % Update channel info (will be used in tables)
            app.capture.ChannelInfoHammer   = app.capture.ChannelInfo(:,app.capture.Trig_Channel);
            app.capture.ChannelInfoResponse = app.capture.ChannelInfo; app.capture.ChannelInfoResponse(:,app.capture.Trig_Channel) = [];
            
            app.capture.Cell_PlotSelTable(:,1)  = {app.capture.ChannelInfoResponse.ID}';
            app.capture.Cell_PlotSelTable(:,2)  = {app.capture.ChannelInfoResponse.Name}';
            app.capture.Cell_PlotSelTable(:,3)  = {app.capture.ChannelInfoResponse.FRFType}';
            app.capture.Cell_PlotSelTable(:,4)  = {app.capture.ChannelInfoResponse.PlotShow}';
            app.Table_PlotSelect.Data           = app.capture.Cell_PlotSelTable;
            
            % Note that clipping levels (min, max) for the actual channels set up are stored in:
            % DAQ_Task.Channels(1, 1).Range.Max/Min
        end
        
        function clearDAQ_Task(app)
            % User changes choice of triggering channel or other 'capture' params,
            % so wipe any DAQ task that has been set up, forcing user to recreate task
            switch exist('app.DAQ_Task','var')
                case 0
                case 1
                    switch app.DAQ_Task.IsRunning
                        case true
                            stop(app.DAQ_Task);
                    end
                    release(app.DAQ_Task);
                    app.DAQ_Task = [];
            end
            ClearRetainedBuffers(app);
            app.TextArea_DAQsetupsummary.Value  = {' '};
            app.TextArea_Console.Value          = {' '};
            app.Button_StartDAQ.Enable          = 'Off';
            app.Button_StopDAQ.Enable           = 'Off';
            app.Button_CaptureKeep.Enable       = 'Off';
        end
        
        function addListeners(app)
            % Add a listener for DataAvailable events and specify the callback function
            % The specified data capture parameters and the handles to the UI graphics
            % elements are passed as additional arguments to the callback function.
            % Add a listener for acquisition error events which might occur during background acquisition
            app.capture.dataListener    = addlistener(app.DAQ_Task, 'DataAvailable', @(src,event) dataCapture(app,event));
            app.capture.errorListener   = addlistener(app.DAQ_Task, 'ErrorOccurred', @(src,event) disp(getReport(event.Error)));
        end
        
        function deleteListeners(app)
            delete(app.capture.dataListener);
            delete(app.capture.errorListener);
        end
        
        function dataCapture(app,event)
            persistent timeBuffer dataBuffer trigActive trigMoment
            
            % If dataCapture is running for the first time, initialize persistent vars
            if event.TimeStamps(1)==0
                timeBuffer = [];          % time buffer
                dataBuffer = [];          % data buffer
                trigActive = false;       % trigger condition flag
                trigMoment = [];          % data timestamp when trigger condition met
            end
            
            % Store continuous acquisition data in persistent FIFO buffer dataBuffer
            %latestTime = event.TimeStamps;
            %latestData = event.Data;
            timeBuffer = [timeBuffer; event.TimeStamps];
            dataBuffer = [dataBuffer; event.Data];
            app.capture.N_SamplesToDiscard = size(dataBuffer,1) - app.capture.N_Buffer;
            if (app.capture.N_SamplesToDiscard > 0)
                timeBuffer(1:app.capture.N_SamplesToDiscard) = [];
                dataBuffer(1:app.capture.N_SamplesToDiscard, :) = [];
            end
            
            % Find correct sample indices for live data plot
            app.capture.samplesToPlot   = min([round(app.capture.T_Buffer*app.capture.Fs), size(dataBuffer,1)]);
            app.capture.firstPoint      = size(dataBuffer, 1)-app.capture.samplesToPlot+1;
            
            % Update live data plot (only show the hammer signal)
            xlim(app.Axes_Live, [timeBuffer(app.capture.firstPoint), timeBuffer(end)]);
            %set(app.handlesPlot.pl1(1), 'XData', timeBuffer(app.capture.firstPoint:end), 'YData', dataBuffer(app.capture.firstPoint:end, app.capture.HammerChannelNo))
            set(app.handlesPlot.pl1(1), 'XData', timeBuffer(app.capture.firstPoint:end), 'YData', dataBuffer(app.capture.firstPoint:end, app.capture.LiveChanIndex))
            
            
            %for nPlot=2:app.capture.chanCount-1
            %    set(app.handlesPlot.pl1(2), 'XData', timeBuffer(app.capture.firstPoint:end), 'YData', dataBuffer(app.capture.firstPoint:end, nPlot))
            %end
            
            %---------------------------------%
            % If trigger conditions is met....
            %---------------------------------%
            if ~trigActive
                [trigActive, trigMoment] = trigDetect(app,event.TimeStamps,event.Data);
                
            elseif trigActive && ((timeBuffer(end)-trigMoment) > app.capture.T_Capture)
                [trigActive] = trigPlot(app,timeBuffer,dataBuffer,trigMoment);
            end
            drawnow;
        end
        
        function [trigDetected, trigMoment] = trigDetect(app,thisTimeBuffer,thisDataBuffer)
            trigCondition       = thisDataBuffer(:, app.capture.Trig_Channel) > app.capture.Trig_Level;
            trigDetected        = any(trigCondition);
            trigMoment          = [];
            if trigDetected
                trigTimeStamps  = thisTimeBuffer(trigCondition, 1);
                trigMoment      = trigTimeStamps(1);
            end
        end
        
        function [trigActive] = trigPlot(app,timeBuffer,dataBuffer,trigMoment)
            %disp('Trigger use worked! Windowed data plot updated!')
            % Immediately stop the DAQ
            Button_StopDAQ_Pushed(app,[]);
            
            % Reset clipping triggers
            app.capture.flag_Clip = zeros(app.capture.chanCount,1);
            
            % Find index of sample in dataBuffer with timestamp value trigMoment
            %trigSampleIndex = find(dataBuffer(:,1) == trigMoment, 1, 'first');
            app.capture.trigSampleIndex = find(timeBuffer == trigMoment, 1);
            
            % Find index of sample in dataBuffer to complete the capture
            app.capture.lastSampleIndex     = round(app.capture.trigSampleIndex+app.capture.T_Capture*app.capture.Fs);
            app.capture.this.CaptureRange   = app.capture.trigSampleIndex-app.capture.Trig_N_PreTrig:app.capture.lastSampleIndex-1-app.capture.Trig_N_PreTrig;
            app.capture.this.CaptureResponse = dataBuffer(app.capture.this.CaptureRange,:);
            
            ClippingChecker(app);
            
            % Organise captured data to separate out hammer signal from all other response signals
            app.capture.this.CaptureHammer  = app.capture.this.CaptureResponse(:,app.capture.Trig_Channel);
            app.capture.this.CaptureResponse(:,app.capture.Trig_Channel) = [];
            
            % Reset trigger flag, to allow for a new triggered data capture
            trigActive                      = false;
            
            % Compute and plot frequency responses and coherence
            calcThisCaptureFRFs(app);
            calcThisCaptureCoherence(app);
            
            % Add last capture to retained buffer: User may then retain or delete it before recommencing DAQ
            weeCount                                    = numel(app.dataRetained);
            app.dataRetained(weeCount+1).data           = app.capture.this;
            app.dataRetained(weeCount+1).metaData{:,1}  = num2str(weeCount+1);
            app.dataRetained(weeCount+1).metaData{:,2}  = char(datetime('now','InputFormat','dd-MMM-yyyy HH:mm:ss'));
            app.dataRetained(weeCount+1).metaData{:,3}  = true;
            
            % Call function that updates the 'Captured Data Management' table to include this latest capture
            %       --> User may later delete it
            app.Table_BufferControl.Data = vertcat(app.dataRetained.metaData);
            
            % Call plotting functions
            plotterThisCapture(app);
            %plotterThisFRFs(app);
            plotterEmsembleFRFs(app);
            
            % Enable the "Keep Capture" button so user can choose to keep captured buffer
            % As a buffer has been captured, force user to either keep or discard it
            app.Button_CaptureKeep.Enable       = 'On';
            app.Button_CaptureDiscard.Enable    = 'On';
            app.Button_PlotLastCapture.Enable   = 'On';
            app.Button_PlotEmsemble.Enable      = 'On';
            app.Button_StartDAQ.Enable          = 'Off';
            app.Button_ClearAllBuffers.Enable   = 'Off';
            app.Button_ClearSelectedBuffers.Enable = 'Off';
            
            assignin('base', 'capture', app.capture);
            assignin('base', 'handlesPlot', app.handlesPlot);
            assignin('base', 'dataRetained', app.dataRetained);
            assignin('base', 'DAQ_Task', app.DAQ_Task);
            assignin('base', 'deviceInfo', app.deviceInfo);
        end
        
        function calcThisCaptureFRFs(app)
            Table_ChannelInfoResponse               = struct2table(app.capture.ChannelInfoResponse);
            SensorTypes                             = Table_ChannelInfoResponse{:,4};
            [app.capture.theseAccIndices,~]         = find(char(SensorTypes)=='a');
            
            % Frequency axis and DFT calculations
            app.capture.this.CaptureHammer_DFT      = fft(app.capture.this.CaptureHammer,2*app.capture.N_Capture);
            app.capture.this.CaptureResponse_DFT    = fft(app.capture.this.CaptureResponse,2*app.capture.N_Capture);
            
            % Now integrate any accelerometer signals in the frequency domain to obtain velocity
            app.capture.this.CaptureResponse_DFT_Integrated = app.capture.this.CaptureResponse_DFT;
            for nChanResp = 1:numel(app.capture.theseAccIndices)
                app.capture.this.CaptureResponse_DFT_Integrated(:,app.capture.theseAccIndices(nChanResp)) = ...
                    app.capture.this.CaptureResponse_DFT_Integrated(:,app.capture.theseAccIndices(nChanResp))./app.capture.omegaVec_i;
                app.capture.this.CaptureResponse_DFT_Integrated(1,app.capture.theseAccIndices(nChanResp)) = 0; % Remove DC nan
            end
            
            % Now compute the admittance calculation
            app.capture.this.CaptureHammer_DFT_Mat = repmat(app.capture.this.CaptureHammer_DFT,[1,app.capture.chanCount-1]);
            app.capture.this.FRF          = app.capture.this.CaptureHammer_DFT_Mat./app.capture.this.CaptureResponse_DFT_Integrated;
            app.capture.this.FRF_dB       = 20*log10(abs(app.capture.this.FRF));
            app.capture.this.FRF_Ph       = (angle(app.capture.this.FRF));
        end
        
        function calcThisCaptureCoherence(app)
            % Estimate the coherence for the current capture in isolation (should always be unity!)
            app.capture.this.Gxy          = app.capture.this.CaptureResponse_DFT_Integrated.*conj(app.capture.this.CaptureHammer_DFT_Mat);
            app.capture.this.Gxx          = app.capture.this.CaptureHammer_DFT_Mat.*conj(app.capture.this.CaptureHammer_DFT_Mat);
            app.capture.this.Gyy          = app.capture.this.CaptureResponse_DFT_Integrated.*conj(app.capture.this.CaptureResponse_DFT_Integrated);
            app.capture.this.Coherence    = (app.capture.this.Gxy.*conj(app.capture.this.Gxy))./(app.capture.this.Gxx.*app.capture.this.Gyy);
        end
        
        function calcEnsembleFRFs(app)
            % Compute mean FRFs/coherence based on which data are in the retained buffer,
            % and which are actively selected by the user
            
            % Find out which entries in the retainedData struct are to be used
            selectedBufferList = find(cell2mat(app.Table_BufferControl.Data(:,3)));
            
            % Estimate the mean coherence across the selected dataset
            app.dataMean.FRF        = zeros(size(app.dataRetained(1).data.FRF));
            app.dataMean.Gxx        = app.dataMean.FRF;
            app.dataMean.Gyy        = app.dataMean.FRF;
            app.dataMean.Gxy        = app.dataMean.FRF;
            app.dataMean.Coherence  = app.dataMean.FRF;
            
            for nBuffer = 1:length(selectedBufferList)
                app.dataMean.FRF = app.dataMean.FRF + app.dataRetained(selectedBufferList(nBuffer)).data.FRF;
                app.dataMean.Gxx = app.dataMean.Gxx + app.dataRetained(selectedBufferList(nBuffer)).data.Gxx;
                app.dataMean.Gyy = app.dataMean.Gyy + app.dataRetained(selectedBufferList(nBuffer)).data.Gyy;
                app.dataMean.Gxy = app.dataMean.Gxy + app.dataRetained(selectedBufferList(nBuffer)).data.Gxy;
            end
            app.dataMean.FRF = app.dataMean.FRF./length(selectedBufferList);
            app.dataMean.FRF_dB = 20*log10(abs(app.dataMean.FRF));
            app.dataMean.FRF_Ph = angle(app.dataMean.FRF);
            app.dataMean.Gxx = app.dataMean.Gxx./length(selectedBufferList);
            app.dataMean.Gyy = app.dataMean.Gyy./length(selectedBufferList);
            app.dataMean.Gxy = app.dataMean.Gxy./length(selectedBufferList);
            
            app.dataMean.Coherence = (app.dataMean.Gxy.*conj(app.dataMean.Gxy))./(app.dataMean.Gxx.*app.dataMean.Gyy);
            
            assignin('base', 'dataMean', app.dataMean);
        end
        
        function plotterThisCapture(app)
            app.Lamp_BufferAverage.Enable  = 'Off';
            switch isempty(app.capture.this)
                case 1
                    % No data in last captured buffer, so empty plots
                    
                    % Update HAMMER data plot
                    set(app.handlesPlot.pl2, 'XData', 0,'YData', 0)
                    
                    % Update RESPONSE data plot (one line for each acquisition channel)
                    for nPlot=1:app.capture.chanCount-1
                        set(app.handlesPlot.pl3(nPlot), 'XData', 0,'YData', 0)
                    end
                case 0
                    % There is data in 'capture.this', so plot it
                    % Update HAMMER data plot
                    set(app.handlesPlot.pl2, 'XData', app.capture.timeVec,'YData', app.capture.this.CaptureHammer)
                    legend([app.handlesPlot.pl2],app.capture.ChannelInfoHammer.Name)
                    
                    % Update RESPONSE data plot (one line for each acquisition channel)
                    for nPlot=1:app.capture.chanCount-1
                        set(app.handlesPlot.pl3(nPlot), 'XData', app.capture.timeVec,'YData', app.capture.this.CaptureResponse(:, nPlot))
                        app.capture.cellLegend{:,nPlot} = app.capture.ChannelInfoResponse(nPlot).Name;
                    end
                    legend([app.handlesPlot.pl3],app.capture.cellLegend)
                    
                    % Axes limits
                    setAxes_Hammer(app);
                    setAxes_Response(app);
            end
        end
        
        function plotterThisFRFs(app)
            app.Lamp_BufferAverage.Enable = 'Off';
            app.handlesPlot.qqq1 = [];
            app.handlesPlot.qqq2 = [];
            app.handlesPlot.cellLegend_thisFRFs = cell(1,1);% {};
            %clear('app.handlesPlot.cellLegend_thisFRFs')% = [];
            
            switch isempty(app.capture.this)
                case 1
                    % No data in last captured buffer, so empty plots
                    for nPlot=1:app.capture.chanCount-1
                        set(app.handlesPlot.plMag(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plPhase(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plCoh(nPlot), 'XData', 0,'YData', 0);
                    end
                case 0
                    % There is data in 'capture.this', so plot the FRFs and turn on light
                    app.Lamp_LastCaptureOnly.Enable = 'On';
                    theseFRFIndices = cell2mat(app.Table_PlotSelect.Data(:,4));
                    countHere = 0;
                    for nPlot=1:app.capture.chanCount-1
                        switch theseFRFIndices(nPlot)
                            case 1
                                countHere = countHere+1;
                                app.handlesPlot.qqq1(countHere) = app.handlesPlot.plCoh(nPlot);
                                app.handlesPlot.qqq2(countHere) = app.handlesPlot.plMag(nPlot);
                                set(app.handlesPlot.plMag(nPlot), 'XData', app.capture.fVec,'YData', app.capture.this.FRF_dB(:,nPlot));
                                %set(app.handlesPlot.plMag(nPlot), 'XData', app.capture.fVec,'YData', real(app.capture.this.FRF(:,nPlot)));
                                set(app.handlesPlot.plPhase(nPlot), 'XData', app.capture.fVec,'YData', app.capture.this.FRF_Ph(:,nPlot));
                                %set(app.handlesPlot.plPhase(nPlot), 'XData', app.capture.fVec,'YData', imag(app.capture.this.FRF(:,nPlot)));
                                set(app.handlesPlot.plCoh(nPlot), 'XData', app.capture.fVec,'YData', app.capture.this.Coherence(:,nPlot));
                                app.handlesPlot.cellLegend_thisFRFs{:,countHere} = app.capture.ChannelInfoResponse(nPlot).Name;
                            otherwise
                                set(app.handlesPlot.plMag(nPlot), 'XData', 0,'YData', 0);
                                set(app.handlesPlot.plPhase(nPlot), 'XData', 0,'YData', 0);
                                set(app.handlesPlot.plCoh(nPlot), 'XData', 0,'YData', 0);
                        end
                    end
                    
                    % Set legend and axes limits as needed
                    formatFRF_Legend_and_Axes(app);
                    %                     switch exist('qqq1','var')
                    %                         case 1
                    %                             % Add legend
                    %                             app.handlesPlot.legendCoh       = legend(qqq1,cellLegend_thisFRFs);
                    %                             app.handlesPlot.legendFRF_dB    = legend(qqq2,cellLegend_thisFRFs);
                    %                         case 0
                    %                             % Remove legend (as user has deselected all FRF lines)
                    %                             set(app.handlesPlot.legendCoh,'Visible','off')
                    %                             set(app.handlesPlot.legendFRF_dB,'Visible','off')
                    %                     end
                    %                     setAxes_Mag(app);
                    %                     setAxes_Phase(app);
                    %                     setAxes_Coherence(app);
            end
        end
        
        function plotterEmsembleFRFs(app)
            % This function plots the ensemble average of admittance (FRF) and coherence
            % of the data selected in the 'Captured Data Management' table
            
            calcEnsembleFRFs(app);
            app.Lamp_LastCaptureOnly.Enable = 'Off';
            app.Lamp_BufferAverage.Enable   = 'On';
            
            app.handlesPlot.qqq1 = [];
            app.handlesPlot.qqq2 = [];
            app.handlesPlot.cellLegend_thisFRFs = cell(1,1);%{};
            
            % Find out which FRFs the user wants displayed
            theseFRFIndices = cell2mat(app.Table_PlotSelect.Data(:,4));
            countHere = 0;
            for nPlot = 1:app.capture.chanCount-1
                switch theseFRFIndices(nPlot)
                    case 1
                        countHere = countHere+1;
                        app.handlesPlot.qqq1(countHere) = app.handlesPlot.plCoh(nPlot);
                        app.handlesPlot.qqq2(countHere) = app.handlesPlot.plMag(nPlot);
                        set(app.handlesPlot.plMag(nPlot), 'XData', app.capture.fVec,'YData', app.dataMean.FRF_dB(:,nPlot));
                        set(app.handlesPlot.plPhase(nPlot), 'XData', app.capture.fVec,'YData', app.dataMean.FRF_Ph(:,nPlot));
                        set(app.handlesPlot.plCoh(nPlot), 'XData', app.capture.fVec,'YData', app.dataMean.Coherence(:,nPlot));
                        app.handlesPlot.cellLegend_thisFRFs{:,countHere} = app.capture.ChannelInfoResponse(nPlot).Name;
                    otherwise
                        set(app.handlesPlot.plMag(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plPhase(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plCoh(nPlot), 'XData', 0,'YData', 0);
                end
            end
            
            % Set legend and axes limits as needed
            formatFRF_Legend_and_Axes(app);
            
            %             app.handlesPlot.legendCoh       = legend(qqq1,cellLegend_thisFRFs);
            %             app.handlesPlot.legendFRF_dB    = legend(qqq2,cellLegend_thisFRFs);
            %             setAxes_Mag(app);
            %             setAxes_Phase(app);
            %             setAxes_Coherence(app)
        end
        
        function setAxes_Hammer(app)
            % This sets the amplitude/time axes limits for the 'hammer' signal
            xlim(app.Axes_Trig_Ham,[0,app.capture.T_Capture]);
            ylim(app.Axes_Trig_Ham,[min(app.capture.this.CaptureHammer),max(app.capture.this.CaptureHammer)]);
        end
        
        function setAxes_Response(app)
            % This sets the amplitude/time axes limits for the 'response' signals
            xlim(app.Axes_Trig_Acc,[0,app.capture.T_Capture]);
            ylim(app.Axes_Trig_Acc,[min(min(app.capture.this.CaptureResponse)),max(max(app.capture.this.CaptureResponse))]);
        end
        
        function setAxes_Mag(app)
            xlim(app.Axes_FreqMag,[app.axLim_Mag_fLow.Value,app.axLim_Mag_fHigh.Value]);
            ylim(app.Axes_FreqMag,[app.axLim_Mag_dBLow.Value,app.axLim_Mag_dBHigh.Value]);
            %ylim(app.Axes_FreqMag,[min(min(app.capture.FRF_dB)),max(max(app.capture.FRF_dB))]);
            % Need a clever way to set y-lims from only first (say) 4kHz of data
        end
        
        function setAxes_Phase(app)
            xlim(app.Axes_FreqPhase,[app.axLim_Mag_fLow.Value,app.axLim_Mag_fHigh.Value]);
            %ylim(app.Axes_FreqPhase,[min(min(app.capture.FRF_Ph)),max(max(app.capture.FRF_Ph))]);
        end
        
        function setAxes_Coherence(app)
            xlim(app.Axes_FreqCoherence,[app.axLim_Mag_fLow.Value,app.axLim_Mag_fHigh.Value]);
            ylim(app.Axes_FreqCoherence,[0,1.1]);
        end
        
        function updateCapDataTable(app)
            app.Table_BufferControl.Data = vertcat(app.dataRetained.metaData);
        end
        
        function formatFRF_Legend_and_Axes(app)
            switch isempty('app.handlesPlot.qqq1')
                case 0
                    % Add legend
                    app.handlesPlot.legendCoh       = legend(app.handlesPlot.qqq1,app.handlesPlot.cellLegend_thisFRFs);
                    app.handlesPlot.legendFRF_dB    = legend(app.handlesPlot.qqq2,app.handlesPlot.cellLegend_thisFRFs);
                case 1
                    % Remove legend (as user has deselected all FRF lines)
                    set(app.handlesPlot.legendCoh,'Visible','off')
                    set(app.handlesPlot.legendFRF_dB,'Visible','off')
            end
            setAxes_Mag(app);
            setAxes_Phase(app);
            setAxes_Coherence(app);
        end
        
        function ClippingChecker(app)
            % Just prior to reorganising the data, check for clipping, and warn user if needed
            for nChan = 1:app.capture.chanCount
                switch app.DAQ_Task.Vendor.ID
                    case 'ni'
                        if max(app.capture.this.CaptureResponse(:,nChan))>app.DAQ_Task.Channels(nChan).Range.Max || ...
                                min(app.capture.this.CaptureResponse(:,nChan))<app.DAQ_Task.Channels(nChan).Range.Min
                            app.capture.flag_Clip(nChan) = 1;
                        end
                    case 'directsound'
                        % Can't rely on expected +/- 1 because Windows never returns 1 even when clipped
                        if max(app.capture.this.CaptureResponse(:,nChan))>0.99 || ...
                                min(app.capture.this.CaptureResponse(:,nChan))<-0.99
                            app.capture.flag_Clip(nChan) = 1;
                        end
                end
            end
            
            % Turn on clipping light
            if any(app.capture.flag_Clip)
                app.Lamp_ClipWarning.Color = 'Red';
                app.Lamp_ClipWarning.Enable = 'On';
            else
                app.Lamp_ClipWarning.Color = 'Green';
                app.Lamp_ClipWarning.Enable = 'On';
            end
            
            % At least 1 (or more) channels has clipped - warn user
            for nChan=1:app.capture.chanCount
                if app.capture.flag_Clip(nChan) == 1
                    thisText_Warning{nChan,1} = ['Chan ID "' app.capture.ChannelInfo(nChan).ID '": CLIP'];
                else
                    thisText_Warning{nChan,1} = ['Chan ID "' app.capture.ChannelInfo(nChan).ID '": OK'];
                end
            end
            app.TextArea_ClipWarning.Value = thisText_Warning;
        end
        
        function LampClipReset(app)
            app.Lamp_ClipWarning.Color          = 'k';
            app.Lamp_ClipWarning.Enable         = 'Off';
            for nChan=1:app.capture.chanCount
                thisText_Warning{nChan,1} = [app.capture.ChannelInfo(nChan).ID ': awaiting capture'];
            end
            app.TextArea_ClipWarning.Value = thisText_Warning;
        end


        function ClearRetainedBuffers(app)
            % User has played around with captured data and deselected buffers they wish to see deleted
            BufferTable         = app.Table_BufferControl.Data;
            
            % Delete the data in the 'dataRetained' structure
            app.dataRetained = [];
            
            % Re-call the calc/plot functions for emsemble FRFs
            switch isempty(BufferTable)
                case 0
                    app.Table_BufferControl.Data = [];
                    % No data in retained buffers, so clear the plots
                    set(app.handlesPlot.pl1, 'XData', 0, 'YData', 0)
                    set(app.handlesPlot.pl2, 'XData', 0, 'YData', 0)
                    for nPlot=1:app.capture.chanCount-1
                        set(app.handlesPlot.pl3(nPlot), 'XData', 0, 'YData', 0)
                        set(app.handlesPlot.plMag(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plPhase(nPlot), 'XData', 0,'YData', 0);
                        set(app.handlesPlot.plCoh(nPlot), 'XData', 0,'YData', 0);
                    end
                    set(app.handlesPlot.legendCoh,'Visible','off')
                    set(app.handlesPlot.legendFRF_dB,'Visible','off')
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialisation stuff when app first launches
            app.counters.chanCount                    = 0;
            app.counters.Count_FindDAQ_ButtonPress    = 0;
            app.counters.Count_deviceType_ButtonPress = 0;
        end

        % Button pushed function: Button_StartDAQ
        function Button_StartDAQ_Pushed(app, event)
            % Remove any previous data in the 'capture.this' structure
            app.capture.this = [];
            
            % Create listeners and commence DAQ session
            addListeners(app);
            startBackground(app.DAQ_Task);
            
            % Turn buttons on/off as needed
            app.Button_StartDAQ.Enable              = 'Off';
            app.Button_StopDAQ.Enable               = 'On';
            app.Button_PlotLastCapture.Enable       = 'Off';
            app.Button_PlotEmsemble.Enable          = 'Off';
        end

        % Button pushed function: Button_StopDAQ
        function Button_StopDAQ_Pushed(app, event)
            switch app.DAQ_Task.IsRunning
                case true
                    stop(app.DAQ_Task);
                    deleteListeners(app);
                    app.Button_StopDAQ.Enable   = 'Off';
            end
            
            % Turn buttons on/off as needed
            app.Button_StartDAQ.Enable              = 'On';
        end

        % Button pushed function: Button_FindDAQdevices
        function Button_FindDAQdevices_Pushed(app, event)
            % This counts the number of button presses (since app behaviour will be different first time vs after repeated selections)
            app.counters.Count_FindDAQ_ButtonPress      = app.counters.Count_FindDAQ_ButtonPress+1;
            app.Lamp_Devicesfound.Enable                = 'Off';
            app.Table_ChannelList.Data                  = [];
            app.Table_SelChannelList.Data               = [];
            app.deviceInfo                              = [];
            app.TextArea_MeasTypes.Value                = {' '};
            app.TextArea_DAQsetupsummary.Value          = {' '};
            app.TextArea_Console.Value                  = {' '};
            
            % Search for all devices and present to user in drop down
            app.deviceInfo.theseDevices                 = daq.getDevices;
            app.deviceInfo.deviceNames                  = {app.deviceInfo.theseDevices.Description}';
            app.DropDown_Device.Items                   = {''};
            app.DropDown_Device.Items                   = ['Select device';app.deviceInfo.deviceNames];
            app.DropDown_DeviceSubSystem.Items          = {'Select subsystem'};
            app.DropDown_SampleRate.Items               = {'Select sample rate (Hz)'};
            
            % Light the lamp to show some devices have been found
            %   --> Takes ages the first time the GUI is run due to DSP System Toolbox
            app.Lamp_Devicesfound.Enable   = 'On';
            app.Button_ChannelSetup.Enable = 'Off';
            
            % Also, empty any data in the 'retained' buffer
            %   --> This ensures we don't have data captures with different DAQ settings from this session
            ClearRetainedBuffers(app)
        end

        % Value changed function: DropDown_Device
        function DropDown_Device_ValueChanged(app, event)
            app.counters.Count_deviceType_ButtonPress = app.counters.Count_deviceType_ButtonPress+1;
            
            % Empty the tables etc
            app.TextArea_MeasTypes.Value        = {' '};
            
            % Update the menu to get rid of the 'select...' text
            app.DropDown_Device.Items           = app.deviceInfo.deviceNames;
            
            % Parse menu according to whether it is first drop down usage or not
            if app.counters.Count_deviceType_ButtonPress > 1
                % When user selects a different device, first clear all the information in the 'deviceInfo'
                % structure (to prevent confusion), then reset the subsystems menu
                app.Button_ChannelSetup.Enable  = 'Off';
                app.Button_StartDAQ.Enable      = 'Off';
                app.Button_CreateDAQTask.Enable = 'Off';
                theseDevices                    = app.deviceInfo.theseDevices;
                theseDeviceNames                = app.deviceInfo.deviceNames;
                app.Table_ChannelList.Data      = [];
                app.Table_SelChannelList.Data   = [];
                app.deviceInfo                  = [];
                app.deviceInfo.theseDevices     = theseDevices;
                app.deviceInfo.deviceNames      = theseDeviceNames;
            end
            
            thisDeviceName                      = app.DropDown_Device.Value;
            [q,~]                               = find(strcmp(app.deviceInfo.deviceNames, thisDeviceName));
            
            % Pull together info for selected device and construct 'subsystem' dropdown
            app.deviceInfo.selectedDevice       = app.deviceInfo.theseDevices(q);
            app.DropDown_DeviceSubSystem.Items  = {'Select subsystem';app.deviceInfo.selectedDevice.Subsystems.SubsystemType};
            %app.DropDown_DeviceSubSystemMeasType.Items  = {'Select measurement type'};
            
            % Populate sample rate drop down (according to whether NI or windows sound card)
            switch app.deviceInfo.selectedDevice.Vendor.ID
                case 'ni'
                    Fs_dropDown_cell = {'51200 Hz';'25600 Hz';'12800 Hz';'6400 Hz'};
                    Fs_dropDown_nums = [51200;25600;12800;6400];
                case 'directsound'
                    Fs_dropDown_cell = {'48000 Hz';'44100 Hz';'32000 Hz';'16000 Hz';'8000 Hz'};
                    Fs_dropDown_nums = [48000;44100;32000;16000;8000];
            end
            app.DropDown_SampleRate.Items       = [Fs_dropDown_cell];
            app.DropDown_SampleRate.ItemsData   = [Fs_dropDown_nums];
        end

        % Value changed function: DropDown_DeviceSubSystem
        function DropDown_DeviceSubSystem_ValueChanged(app, event)
            app.Table_ChannelList.Data      = [];
            app.Table_SelChannelList.Data   = [];
            app.TextArea_MeasTypes.Value    = {' '};
            
            % Update the menu to get rid of the 'select...' text
            app.DropDown_DeviceSubSystem.Items  = {app.deviceInfo.selectedDevice.Subsystems.SubsystemType};
            
            % When user selects a subsystem, pull out the info about measurement types available and present to user
            thisSubsystemType                   = app.DropDown_DeviceSubSystem.Value;
            [q,~]                               = find(strcmp(app.deviceInfo.selectedDevice.Subsystems.SubsystemType, thisSubsystemType));
            
            % Pull together info for selected device and construct 'subsystem' dropdown
            app.deviceInfo.selectedSubsystem    = app.deviceInfo.selectedDevice.Subsystems(q);
            
            % And inform user about the types of measurement available
            %   --> Can be different for each channel, e.g. IEPE hammer, mic, voltage etc
            for nTypes = 1:numel(app.deviceInfo.selectedSubsystem.MeasurementTypesAvailable)
                thisType                                            = app.deviceInfo.selectedSubsystem.MeasurementTypesAvailable{nTypes};
                app.deviceInfo.measTypes{nTypes,1}                  = thisType;
                app.deviceInfo.measInfoCell{nTypes,1}               = [app.deviceInfo.measTypes{nTypes,1} ' (' thisType(1:2) ')'];
                app.deviceInfo.measInfoCell_FirstLetters{nTypes,1}  = thisType(1:2);
            end
            app.TextArea_MeasTypes.Value = app.deviceInfo.measInfoCell;
            
            % Construct main channel info TABLE, ready for user to label/select input channels
            cellChannelNumbers  = num2cell([1:app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable]');
            cellChannelNames    = reshape(app.deviceInfo.selectedSubsystem.ChannelNames,[app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable,1]);
            [cellChannelUnits{1:app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable,1}] = ...
                deal(app.deviceInfo.selectedSubsystem.RangesAvailable.Units);
            [cellDataRange{1:app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable,1}] = ...
                deal(char([num2str(app.deviceInfo.selectedSubsystem.RangesAvailable.Min) ' to ' num2str(app.deviceInfo.selectedSubsystem.RangesAvailable.Max)]));
            cellChannelSelect   = false(app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable,1);
            cellChannelSelect   = num2cell(cellChannelSelect);
            cellChannelMeasType = repmat({app.deviceInfo.measInfoCell_FirstLetters{1}},[app.deviceInfo.selectedSubsystem.NumberOfChannelsAvailable,1]);
            
            app.capture.channelTableData_Cell = [cellChannelNumbers,cellChannelNames,cellChannelUnits,cellDataRange,cellChannelSelect,cellChannelMeasType];
            app.Table_ChannelList.Data = app.capture.channelTableData_Cell;
        end

        % Cell edit callback: Table_ChannelList
        function Table_ChannelList_CellEdit(app, event)
            % This callback activates when user selects (i.e. edits) a channel to be added to the DAQ task
            % We use this to keep track of which channels are requested, and the total number (needs to be at least 2)
            app.capture.chanSelected_logical   = cell2mat(app.Table_ChannelList.Data(:,5));
            app.capture.chanCount              = sum(app.capture.chanSelected_logical);
            
            if app.capture.chanCount>=2
                % If at least 2 channels are selected, enable the 'create DAQ task' button
                app.Button_ChannelSetup.Enable = 'On';
            else
                % Otherwise, it should be disabled
                app.Button_ChannelSetup.Enable = 'Off';
            end
        end

        % Button pushed function: Button_ChannelSetup
        function Button_ChannelSetup_Pushed(app, event)
            % Clear all buffers
            ClearRetainedBuffers(app)
            
            app.counters.errorFlag = 0;
            app.TextArea_DAQsetupsummary.Value = ' ';
            app.TextArea_Console.Value         = ' ';
            
            % (1) Identify the selected channels and update metadata/calibration table
            %       --> Pulls out the indices (from the table) of the selected channels
            chanListSel = find(app.capture.chanSelected_logical);
            
            % (2) Loop over requested channels and add them 1 by 1 to the channel metadata table
            for nChan = 1:app.capture.chanCount
                cellChanName{nChan,1}           = 'Edit channel name';
                cellChanID{nChan,1}             = char(app.deviceInfo.selectedSubsystem.ChannelNames(chanListSel(nChan)));
                cellChanCalibration{nChan,1}    = num2str(1);
                cellChanType{nChan,1}           = 'f, a, v, m?';
                cellMeasType{nChan,1}           = char(app.Table_ChannelList.Data{nChan,6});
                %app.capture.ClipLimit(nChan,1)  = app.Table_ChannelList.Data{nChan,6};
                switch cellMeasType{nChan,1}
                    case app.deviceInfo.measInfoCell_FirstLetters
                        switch cellMeasType{nChan,1}
                            case {'Mi';'Ac';'IE'}
                                cellIEPElog{nChan,1} = true;
                            otherwise
                                cellIEPElog{nChan,1} = false;
                        end
                    otherwise
                        app.TextArea_Console.Value      = {'Error: One of your requested channels has been mislabelled.';'See --Guide to measurement types available--'};
                        app.counters.errorFlag          = app.counters.errorFlag+1; % Increment error flag for this latest button click (will terminate process)
                        app.Table_SelChannelList.Data   = []; % Clear channel spec table
                end
            end
            
            % (3) Proceed to populate channel setup table if no errors have been thrown
            switch app.counters.errorFlag
                case 0
                    app.deviceInfo.channelSelTableData_Cell     = [cellChanID,cellChanName,cellChanType,cellChanCalibration,cellIEPElog,cellMeasType];
                    app.Table_SelChannelList.Data               = app.deviceInfo.channelSelTableData_Cell;
                    
                    % (2) Update list of trigger channels
                    app.DropDown_Triggeringchannel.Items = cellChanID;
                    
                    % (3) Enable the 'create DAQ session' button
                    app.Button_CreateDAQTask.Enable = 'On';
                    
                otherwise
                    % Make sure the 'create DAQ session' button is not enabled
                    app.Button_CreateDAQTask.Enable = 'Off';
            end
            
            % (4) Populate 'live' plot drop-down menu
            app.DropDown_LiveChan.Items     = cellChanID;
            app.DropDown_LiveChan.ItemsData = 1:app.capture.chanCount;
            app.capture.LiveChanIndex       = 1; % Index of the 'current' live channel (only affect 'Live' plot)
        end

        % Button pushed function: Button_CreateDAQTask
        function Button_CreateDAQTaskPushed(app, event)
            % Clear all buffers
            ClearRetainedBuffers(app)
            
            % Set up the DAQ task based on user specified channel info
            % First of all, make sure the channels have been labelled with appropriate sensor types
            %       --> 'f' for force (i.e. impact hammer), 'a' for accelerometer, 'v' for velocity, 'm' for microphone
            %       --> Note that we only allow a single 'force'-type input channel
            chanType_Flag = 0;
            count_f_Flag  = 0;
            
            % Create error flags based on checking number of 'f' type sensors and allowed sensor types
            for nChan=1:app.capture.chanCount
                thisSensorType = char(app.Table_SelChannelList.Data(nChan,3));
                switch thisSensorType
                    case {'f';'a';'v';'m'}
                        % Do nothing, as user has correctly assigned the channel as one of the allowed sensor types
                        %   --> But do record if the channel type is 'f' or not (as only 1 'f'-type is allowed)
                        %   --> And do make a note of which channel ID 'f' is on
                        if thisSensorType == 'f'
                            count_f_Flag                = count_f_Flag + 1;
                            app.capture.HammerChannelNo = nChan;
                        end
                    otherwise
                        % Incrememnt flag, which will stop DAQ session being created
                        chanType_Flag = chanType_Flag+1;
                end
            end
            
            % Now we have flags, run through DAQ setup, aborting or proceeding as required
            switch count_f_Flag
                case 0  % User has not specified a 'force' type channel, so do not proceed and throw error
                    app.TextArea_Console.Value = {'Error: No force-type (f) sensor listed.';'Specify one of the input channels as f-type.'};
                case 1  % User has specified exactly 1 'force' type channel as required, so proceed to create DAQ task
                    switch chanType_Flag    % Now check whether any incorrectly labelled sensor types have been supplied
                        case 0
                            % Call the function that sets up the DAQ task, triggering, plots etc (first delete any existing task)
                            switch exist('app.DAQ_Task','var')
                                case 1
                                    release(app.DAQ_Task);
                            end
                            app.DAQ_Task = [];
                            setupDAQ(app);
                            app.Button_StartDAQ.Enable = 'On';
                            app.Button_CaptureKeep.Enable = 'Off';
                            prepare(app.DAQ_Task);
                        otherwise
                            % Stop the DAQ setup
                            app.TextArea_Console.Value = {'Error: Incorrectly specified sensor types.';'Edit and try again.'};
                    end
                otherwise
                    % Stop the DAQ setup as too many 'f' type sensors have been specified
                    app.TextArea_Console.Value = {'Error: More than one force-type (f) sensor listed.';'Only 1 allowed.'};
            end
        end

        % Value changed function: DropDown_Triggeringchannel
        function DropDown_TriggeringchannelValueChanged(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: NumBox_TriggerLevel
        function NumBox_TriggerLevelValueChanged(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: NumBox_Durationofacquisition
        function NumBox_DurationofacquisitionValueChanged(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: NumBox_Durationofpretrigger
        function NumBox_DurationofpretriggerValueChanged(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: NumBox_DurationOfBuffer
        function NumBox_DurationOfBufferValueChanged(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: Switch_TrigIn_Pan
        function Switch_TrigIn_PanValueChanged(app, event)
            value = app.Switch_TrigIn_Pan.Value;
            switch value
                case 'On'
                    app.Switch_TrigIn_Zoom.Value = 'Off';
                    zoom(app.Axes_Trig_Ham,'off');
                    pan(app.Axes_Trig_Ham,'on');
                case 'Off'
                    pan(app.Axes_Trig_Ham,'off');
            end
        end

        % Value changed function: Switch_TrigIn_Zoom
        function Switch_TrigIn_ZoomValueChanged(app, event)
            value = app.Switch_TrigIn_Zoom.Value;
            switch value
                case 'On'
                    app.Switch_TrigIn_Pan.Value = 'Off';
                    pan(app.Axes_Trig_Ham,'off');
                    zoom(app.Axes_Trig_Ham,'on');
                case 'Off'
                    zoom(app.Axes_Trig_Ham,'off');
            end
        end

        % Button pushed function: Button_TrigIn_ResetPlot
        function Button_TrigIn_ResetPlotPushed(app, event)
            setAxes_Hammer(app);
        end

        % Value changed function: Switch_TrigOuts_Pan
        function Switch_TrigOuts_PanValueChanged(app, event)
            value = app.Switch_TrigOuts_Pan.Value;
            switch value
                case 'On'
                    app.Switch_TrigOuts_Zoom.Value = 'Off';
                    zoom(app.Axes_Trig_Acc,'off');
                    pan(app.Axes_Trig_Acc,'on');
                case 'Off'
                    pan(app.Axes_Trig_Acc,'off');
            end
        end

        % Value changed function: Switch_TrigOuts_Zoom
        function Switch_TrigOuts_ZoomValueChanged(app, event)
            value = app.Switch_TrigOuts_Zoom.Value;
            switch value
                case 'On'
                    app.Switch_TrigOuts_Pan.Value = 'Off';
                    pan(app.Axes_Trig_Acc,'off');
                    zoom(app.Axes_Trig_Acc,'on');
                case 'Off'
                    zoom(app.Axes_Trig_Acc,'off');
            end
        end

        % Button pushed function: Button_TrigOuts_ResetPlot
        function Button_TrigOuts_ResetPlotPushed(app, event)
            setAxes_Response(app);
        end

        % Value changed function: Switch_Mag_Pan
        function Switch_Mag_PanValueChanged(app, event)
            value = app.Switch_Mag_Pan.Value;
            switch value
                case 'On'
                    app.Switch_Mag_Zoom.Value = 'Off';
                    zoom(app.Axes_FreqMag,'off');
                    pan(app.Axes_FreqMag,'on');
                case 'Off'
                    pan(app.Axes_FreqMag,'off');
            end
        end

        % Value changed function: Switch_Mag_Zoom
        function Switch_Mag_ZoomValueChanged(app, event)
            value = app.Switch_Mag_Zoom.Value;
            switch value
                case 'On'
                    app.Switch_Mag_Pan.Value = 'Off';
                    pan(app.Axes_FreqMag,'off');
                    zoom(app.Axes_FreqMag,'on');
                case 'Off'
                    zoom(app.Axes_FreqMag,'off');
            end
        end

        % Button pushed function: Button_Mag_ResetPlot
        function Button_Mag_ResetPlotPushed(app, event)
            setAxes_Mag(app);
        end

        % Value changed function: Switch_Phase_Pan
        function Switch_Phase_PanValueChanged(app, event)
            value = app.Switch_Phase_Pan.Value;
            switch value
                case 'On'
                    app.Switch_Phase_Zoom.Value = 'Off';
                    zoom(app.Axes_FreqPhase,'off');
                    pan(app.Axes_FreqPhase,'on');
                case 'Off'
                    pan(app.Axes_FreqPhase,'off');
            end
        end

        % Value changed function: Switch_Phase_Zoom
        function Switch_Phase_ZoomValueChanged(app, event)
            value = app.Switch_Phase_Zoom.Value;
            switch value
                case 'On'
                    app.Switch_Phase_Pan.Value = 'Off';
                    pan(app.Axes_FreqPhase,'off');
                    zoom(app.Axes_FreqPhase,'on');
                case 'Off'
                    zoom(app.Axes_FreqPhase,'off');
            end
        end

        % Button pushed function: Button_Phase_ResetPlot
        function Button_Phase_ResetPlotPushed(app, event)
            setAxes_Phase(app);
        end

        % Cell edit callback: Table_SelChannelList
        function Table_SelChannelListCellEdit(app, event)
            clearDAQ_Task(app);
        end

        % Value changed function: axLim_Mag_fLow
        function axLim_Mag_fLowValueChanged(app, event)
            setAxes_Mag(app);
        end

        % Value changed function: axLim_Mag_fHigh
        function axLim_Mag_fHighValueChanged(app, event)
            setAxes_Mag(app);
        end

        % Value changed function: axLim_Mag_dBLow
        function axLim_Mag_dBLowValueChanged(app, event)
            setAxes_Mag(app);
        end

        % Value changed function: axLim_Mag_dBHigh
        function axLim_Mag_dBHighValueChanged(app, event)
            setAxes_Mag(app);
        end

        % Button pushed function: Button_PlotLastCapture
        function Button_PlotLastCapturePushed(app, event)
            plotterThisFRFs(app);
        end

        % Button pushed function: Button_PlotEmsemble
        function Button_PlotEmsemblePushed(app, event)
            plotterEmsembleFRFs(app);
        end

        % Button pushed function: Button_CaptureKeep
        function Button_CaptureKeepPushed(app, event)
            % Signals have been captured, and user wants to keep the 'current' buffer
            %   --> As it's already in retained buffer, simple do nothing but tweak buttons
            % Stop multiple button presses and allow user to make another capture
            app.Button_CaptureKeep.Enable       = 'Off';
            app.Button_CaptureDiscard.Enable    = 'Off';
            app.Button_StartDAQ.Enable          = 'On';
            app.Button_ClearAllBuffers.Enable   = 'On';
            app.Button_ClearSelectedBuffers.Enable = 'On';
            LampClipReset(app);
            
            assignin('base', 'dataRetained', app.dataRetained);
        end

        % Button pushed function: Button_CaptureDiscard
        function Button_CaptureDiscardPushed(app, event)
            % Delete the last capture from both the 'live' buffer and the retained buffer, and update plots
            app.capture.this                = [];
            app.dataRetained                = app.dataRetained(1:end-1);
            app.Table_BufferControl.Data    = vertcat(app.dataRetained.metaData);
            plotterThisFRFs(app);
            
            % Stop multiple button presses and allow user to make another capture
            app.Button_CaptureKeep.Enable       = 'Off';
            app.Button_CaptureDiscard.Enable    = 'Off';
            app.Button_PlotLastCapture.Enable   = 'Off';
            app.Button_StartDAQ.Enable          = 'On';
            app.Button_ClearAllBuffers.Enable   = 'On';
            app.Button_ClearSelectedBuffers.Enable = 'On';
            LampClipReset(app);
            
            assignin('base', 'dataRetained', app.dataRetained);
        end

        % Button pushed function: Button_ClearSelectedBuffers
        function Button_ClearSelectedBuffersPushed(app, event)
            % User has played around with captured data and deselected buffers they wish to see deleted
            BufferTable         = app.Table_BufferControl.Data;
            switch isempty(BufferTable)
                case 0
                    theseBufferIndices  = cell2mat(BufferTable(:,3));
                    deleteBufferIndices = find(~theseBufferIndices);
                    
                    % Delete the data in the 'dataRetained' structure
                    app.dataRetained(deleteBufferIndices) = [];
                    
                    % Delete appropriate rows from the table and replot it
                    BufferTable(deleteBufferIndices,:) = [];
                    app.Table_BufferControl.Data = BufferTable;
                    
                    % Re-call the calc/plot functions for emsemble FRFs
                    switch isempty(BufferTable)
                        case 1
                            % No data in retained buffers, so clear the plots
                            for nPlot=1:app.capture.chanCount-1
                                set(app.handlesPlot.plMag(nPlot), 'XData', 0,'YData', 0);
                                set(app.handlesPlot.plPhase(nPlot), 'XData', 0,'YData', 0);
                                set(app.handlesPlot.plCoh(nPlot), 'XData', 0,'YData', 0);
                            end
                            set(app.handlesPlot.legendCoh,'Visible','off')
                            set(app.handlesPlot.legendFRF_dB,'Visible','off')
                        otherwise
                            % Data available, so call the calc/plotter
                            plotterEmsembleFRFs(app);
                    end
            end
        end

        % Button pushed function: Button_ClearAllBuffers
        function Button_ClearAllBuffersPushed(app, event)
            % Clear all buffers
            ClearRetainedBuffers(app)
            
            % Turn off/on buttons as needed
            app.Button_CaptureKeep.Enable       = 'Off';
            app.Button_CaptureDiscard.Enable    = 'Off';
            app.Button_StartDAQ.Enable          = 'On';
            app.Button_StopDAQ.Enable           = 'Off';
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)


        end

        % Value changed function: DropDown_LiveChan
        function DropDown_LiveChanValueChanged(app, event)
            % Update the globally accessible record of the requested live channel
            %   --> If DAQ task is running, this will update the live plot on the 
            %       next excecution of the function "datCapture"
            app.capture.LiveChanIndex = app.DropDown_LiveChan.Value;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1_SetupDAQ = uitab(app.TabGroup);
            app.Tab1_SetupDAQ.Title = '(I) Setup DAQ, Sensor/Channel Naming, Trigger Setup';
            app.Panel_DAQDeviceSetup = uipanel(app.Tab1_SetupDAQ);
            app.Panel_DAQDeviceSetup.Title = 'Stage (1) - Initial DAQ Device Setup';
            app.Button_FindDAQdevices = uibutton(app.Panel_DAQDeviceSetup, 'push');
            app.Button_FindDAQdevices.ButtonPushedFcn = createCallbackFcn(app, @Button_FindDAQdevices_Pushed, true);
            app.Button_FindDAQdevices.Text = 'Find DAQ devices';
            app.DropDown_Device = uidropdown(app.Panel_DAQDeviceSetup);
            app.DropDown_Device.Items = {'Select device'};
            app.DropDown_Device.ValueChangedFcn = createCallbackFcn(app, @DropDown_Device_ValueChanged, true);
            app.DropDown_Device.Value = 'Select device';
            app.DropDown_DeviceSubSystem = uidropdown(app.Panel_DAQDeviceSetup);
            app.DropDown_DeviceSubSystem.Items = {'Select subsystem'};
            app.DropDown_DeviceSubSystem.ValueChangedFcn = createCallbackFcn(app, @DropDown_DeviceSubSystem_ValueChanged, true);
            app.DropDown_DeviceSubSystem.Value = 'Select subsystem';
            app.DevicesfoundLampLabel = uilabel(app.Panel_DAQDeviceSetup);
            app.DevicesfoundLampLabel.Text = 'Devices found?';
            app.Lamp_Devicesfound = uilamp(app.Panel_DAQDeviceSetup);
            app.Lamp_Devicesfound.Enable = 'off';
            app.DropDown_SampleRate = uidropdown(app.Panel_DAQDeviceSetup);
            app.DropDown_SampleRate.Items = {'Select sample rate (Hz)'};
            app.DropDown_SampleRate.Value = 'Select sample rate (Hz)';
            app.TextArea_MeasTypes = uitextarea(app.Panel_DAQDeviceSetup);
            app.MeasurementtypesavailableLabel = uilabel(app.Panel_DAQDeviceSetup);
            app.MeasurementtypesavailableLabel.Text = 'Measurement types available:';
            app.Table_ChannelList = uitable(app.Panel_DAQDeviceSetup);
            app.Table_ChannelList.ColumnName = {'Input Channel #'; 'Channel ID'; 'Raw Units'; 'Raw Data Range'; 'Add to Acquisition?'; 'Measurement type?'};
            app.Table_ChannelList.RowName = {};
            app.Table_ChannelList.ColumnEditable = [false false false false true true];
            app.Table_ChannelList.CellEditCallback = createCallbackFcn(app, @Table_ChannelList_CellEdit, true);
            app.Panel = uipanel(app.Panel_DAQDeviceSetup);
            app.Button_ChannelSetup = uibutton(app.Panel, 'push');
            app.Button_ChannelSetup.ButtonPushedFcn = createCallbackFcn(app, @Button_ChannelSetup_Pushed, true);
            app.Button_ChannelSetup.Enable = 'off';
            app.Button_ChannelSetup.Text = '(1) Setup Channels';
            app.EditcolumnLabel = uilabel(app.Panel_DAQDeviceSetup);
            app.EditcolumnLabel.Text = '[Edit column]';
            app.EditcolumnLabel_2 = uilabel(app.Panel_DAQDeviceSetup);
            app.EditcolumnLabel_2.Text = '[Edit column]';
            app.Panel_ChannelSetup = uipanel(app.Tab1_SetupDAQ);
            app.Panel_ChannelSetup.Title = 'Stage (2) - Input Channel Naming, Calibration, and Triggering Setup';
            app.Table_SelChannelList = uitable(app.Panel_ChannelSetup);
            app.Table_SelChannelList.ColumnName = {'Channel ID'; 'Channel Name'; 'Sensor Type (f, a, v, m)'; 'Sensitivity (mV/#)'; 'IEPE Power (NI only)'; 'Meas Type'};
            app.Table_SelChannelList.RowName = {};
            app.Table_SelChannelList.ColumnEditable = [false true true true false false];
            app.Table_SelChannelList.CellEditCallback = createCallbackFcn(app, @Table_SelChannelListCellEdit, true);
            app.CheckunitscarefullyifcalibrationappliedLabel = uilabel(app.Panel_ChannelSetup);
            app.CheckunitscarefullyifcalibrationappliedLabel.Text = '(Check units carefully if calibration applied!)';
            app.DurationoftriggeredacquisitionsLabel = uilabel(app.Panel_ChannelSetup);
            app.DurationoftriggeredacquisitionsLabel.Text = 'Duration of triggered acquisition (s)';
            app.NumBox_Durationofacquisition = uieditfield(app.Panel_ChannelSetup, 'numeric');
            app.NumBox_Durationofacquisition.Limits = [0.001 Inf];
            app.NumBox_Durationofacquisition.ValueChangedFcn = createCallbackFcn(app, @NumBox_DurationofacquisitionValueChanged, true);
            app.NumBox_Durationofacquisition.Value = 0.5;
            app.DurationofpretriggersEditFieldLabel = uilabel(app.Panel_ChannelSetup);
            app.DurationofpretriggersEditFieldLabel.Text = 'Duration of pre-trigger (s)';
            app.NumBox_Durationofpretrigger = uieditfield(app.Panel_ChannelSetup, 'numeric');
            app.NumBox_Durationofpretrigger.ValueChangedFcn = createCallbackFcn(app, @NumBox_DurationofpretriggerValueChanged, true);
            app.NumBox_Durationofpretrigger.Value = 0.01;
            app.TriggeringchannelDropDownLabel = uilabel(app.Panel_ChannelSetup);
            app.TriggeringchannelDropDownLabel.Text = 'Triggering channel';
            app.DropDown_Triggeringchannel = uidropdown(app.Panel_ChannelSetup);
            app.DropDown_Triggeringchannel.Items = {'Channel ID 1'};
            app.DropDown_Triggeringchannel.ValueChangedFcn = createCallbackFcn(app, @DropDown_TriggeringchannelValueChanged, true);
            app.DropDown_Triggeringchannel.Value = 'Channel ID 1';
            app.DAQSetupSummaryLabel = uilabel(app.Panel_ChannelSetup);
            app.DAQSetupSummaryLabel.Text = 'DAQ Setup Summary:';
            app.Label = uilabel(app.Panel_ChannelSetup);
            app.Label.Text = '';
            app.TextArea_DAQsetupsummary = uitextarea(app.Panel_ChannelSetup);
            app.TextArea_DAQsetupsummary.Editable = 'off';
            app.NumBox_DurationOfBuffer = uieditfield(app.Panel_ChannelSetup, 'numeric');
            app.NumBox_DurationOfBuffer.Limits = [0.5 10];
            app.NumBox_DurationOfBuffer.ValueChangedFcn = createCallbackFcn(app, @NumBox_DurationOfBufferValueChanged, true);
            app.NumBox_DurationOfBuffer.Value = 3;
            app.DurationofrunningbuffersLabel = uilabel(app.Panel_ChannelSetup);
            app.DurationofrunningbuffersLabel.Text = 'Duration of running buffer (s)';
            app.Panel_2 = uipanel(app.Panel_ChannelSetup);
            app.TextArea_Console = uitextarea(app.Panel_2);
            app.InfoconsoleLabel = uilabel(app.Panel_2);
            app.InfoconsoleLabel.Text = 'Info console:';
            app.Button_CreateDAQTask = uibutton(app.Panel_2, 'push');
            app.Button_CreateDAQTask.ButtonPushedFcn = createCallbackFcn(app, @Button_CreateDAQTaskPushed, true);
            app.Button_CreateDAQTask.Enable = 'off';
            app.Button_CreateDAQTask.Text = '(2) Create DAQ task';
            app.EditcolumnLabel_3 = uilabel(app.Panel_ChannelSetup);
            app.EditcolumnLabel_3.Text = '[Edit column]';
            app.EditcolumnLabel_4 = uilabel(app.Panel_ChannelSetup);
            app.EditcolumnLabel_4.Text = '[Edit column]';
            app.EditcolumnLabel_5 = uilabel(app.Panel_ChannelSetup);
            app.EditcolumnLabel_5.Text = '[Edit column]';
            app.NumBox_DurationOfBuffer_2 = uieditfield(app.Panel_ChannelSetup, 'numeric');
            app.NumBox_DurationOfBuffer_2.Limits = [0.5 10];
            app.NumBox_DurationOfBuffer_2.Editable = 'off';
            app.NumBox_DurationOfBuffer_2.Value = 1;
            app.PlotrefreshperiodsLabel = uilabel(app.Panel_ChannelSetup);
            app.PlotrefreshperiodsLabel.Text = 'Plot refresh period (s)';
            app.TriggerlevelamplitudeLabel = uilabel(app.Panel_ChannelSetup);
            app.TriggerlevelamplitudeLabel.Text = 'Trigger level amplitude';
            app.NumBox_TriggerLevel = uieditfield(app.Panel_ChannelSetup, 'numeric');
            app.NumBox_TriggerLevel.ValueChangedFcn = createCallbackFcn(app, @NumBox_TriggerLevelValueChanged, true);
            app.NumBox_TriggerLevel.Value = 10;
            app.InstructionsforStage1Panel = uipanel(app.Tab1_SetupDAQ);
            app.InstructionsforStage1Panel.Title = 'Instructions for Stage (1)';
            app.GuidetomeasurementtypesavailablePanel = uipanel(app.InstructionsforStage1Panel);
            app.GuidetomeasurementtypesavailablePanel.Title = 'Guide to measurement types available:';
            app.TextArea_MeasTypes_2 = uitextarea(app.GuidetomeasurementtypesavailablePanel);
            app.TextArea_MeasTypes_2.Value = {'''directaudio'' systems'; 'Audio (Au) - For regular soundcards (i.e. no choice here, all signal types are seen as the same at the ADC level)'; ''; '''ni'' systems'; 'Voltage (Vo) - Sensors that don''t require power through DAQ'; 'Accelerometer (Ac) - IEPE-powered accelerometers/hammers (use this for BOTH accelerometers and hammers if using IEPE powering)'; 'Microphone (Mi) - IEPE-powered microphones (GRAS/B&K etc)'; 'IEPE (IE) - Generic IEPE-powered sensors'};
            app.StepbystepPanel = uipanel(app.InstructionsforStage1Panel);
            app.StepbystepPanel.Title = 'Step by step';
            app.TextArea_MeasTypes_3 = uitextarea(app.StepbystepPanel);
            app.TextArea_MeasTypes_3.Value = {'(i) Click "Find DAQ devices" and wait for green light (takes a while the first time you run the app)'; '(ii) Select required device'; '(iii) Select required subsystem (usually "XXXInput" or similar)'; '(iv) Select required sample rate from drop-down'; '(v) Select required channels in table (select AT LEAST 2 channels)'; '(vi) Type measurement type letter (A, V, M etc) for each channel (see further details below)'; '(vii) Click "(1) Setup Channels" and proceed to Stage (2)'};
            app.InstructionsforStage2Panel = uipanel(app.Tab1_SetupDAQ);
            app.InstructionsforStage2Panel.Title = 'Instructions for Stage (2)';
            app.GuidetosensortypesavailablePanel = uipanel(app.InstructionsforStage2Panel);
            app.GuidetosensortypesavailablePanel.Title = 'Guide to sensor types available:';
            app.TextArea_MeasTypes_4 = uitextarea(app.GuidetosensortypesavailablePanel);
            app.TextArea_MeasTypes_4.Value = {'Force/impact hammer (f) - Only one of the selected channels may be of this type [available for measurement types: Audio (Au), Voltage (Vo), Accelerometer (A), IEPE (I)] '; ''; 'Accelerometer (a) - No limit on channel count (note that all a-type signals are integrated in frequency domain to obtain admittance) [available for measurement types: Accelerometer (Au), IEPE (IE)]'; ''; 'Velocity (v) - e.g. from an LDV output [available for measurement types: Audio (Au), Voltage (Vo)]'; ''; 'Microphone pressure (m) - No limit on channel count [available for measurement types: Audio (Au), Voltage (Vo), Microphone (Mi), IEPE (IE)]'};
            app.StepbystepPanel_2 = uipanel(app.InstructionsforStage2Panel);
            app.StepbystepPanel_2.Title = 'Step by step';
            app.TextArea_MeasTypes_5 = uitextarea(app.StepbystepPanel_2);
            app.TextArea_MeasTypes_5.Value = {'(i) Add descriptive name for each channel (e.g. "PCB hammer")'; '(ii) Type in appropriate sensor type for each channel (e.g. ''f'' - also see below) '; '(iii) If using an IEPE sensor powered by an "ni" type interface, type the sensitivity of the sensor (e.g. 50mV/Pa for a microphone) '; '(iv) Adjust the analog triggering setup if required'; '(v) Click "(2) Create DAQ Task" and if all is well, proceed to tab (II)'};
            app.Tab2_Measure = uitab(app.TabGroup);
            app.Tab2_Measure.Title = '(II) Measurement, Data Export';
            app.Axes_Live = uiaxes(app.Tab2_Measure);
            title(app.Axes_Live, 'Live sensor data [select channel from left hand drop-down]')
            xlabel(app.Axes_Live, 'Time (s)')
            ylabel(app.Axes_Live, 'Amplitude')
            app.Axes_Trig_Ham = uiaxes(app.Tab2_Measure);
            title(app.Axes_Trig_Ham, '(Most recent) Captured sensor data [hammer]')
            xlabel(app.Axes_Trig_Ham, 'Time (s)')
            ylabel(app.Axes_Trig_Ham, 'Amplitude')
            app.Axes_Trig_Acc = uiaxes(app.Tab2_Measure);
            title(app.Axes_Trig_Acc, '(Most recent) Captured sensor data [response signals]')
            xlabel(app.Axes_Trig_Acc, 'Time (s)')
            ylabel(app.Axes_Trig_Acc, 'Amplitude')
            app.Panel_3 = uipanel(app.Tab2_Measure);
            app.Button_StartDAQ = uibutton(app.Panel_3, 'push');
            app.Button_StartDAQ.ButtonPushedFcn = createCallbackFcn(app, @Button_StartDAQ_Pushed, true);
            app.Button_StartDAQ.Enable = 'off';
            app.Button_StartDAQ.Text = 'Start DAQ';
            app.Button_StopDAQ = uibutton(app.Panel_3, 'push');
            app.Button_StopDAQ.ButtonPushedFcn = createCallbackFcn(app, @Button_StopDAQ_Pushed, true);
            app.Button_StopDAQ.Enable = 'off';
            app.Button_StopDAQ.Text = 'Stop DAQ';
            app.PlotControlsPanel = uipanel(app.Tab2_Measure);
            app.PlotControlsPanel.Title = 'Plot Controls';
            app.Button_TrigIn_ResetPlot = uibutton(app.PlotControlsPanel, 'push');
            app.Button_TrigIn_ResetPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_TrigIn_ResetPlotPushed, true);
            app.Button_TrigIn_ResetPlot.Text = 'Reset View';
            app.PanSwitch_2Label = uilabel(app.PlotControlsPanel);
            app.PanSwitch_2Label.Text = 'Pan';
            app.Switch_TrigIn_Pan = uiswitch(app.PlotControlsPanel, 'slider');
            app.Switch_TrigIn_Pan.ValueChangedFcn = createCallbackFcn(app, @Switch_TrigIn_PanValueChanged, true);
            app.ZoomSwitch_2Label = uilabel(app.PlotControlsPanel);
            app.ZoomSwitch_2Label.Text = 'Zoom';
            app.Switch_TrigIn_Zoom = uiswitch(app.PlotControlsPanel, 'slider');
            app.Switch_TrigIn_Zoom.ValueChangedFcn = createCallbackFcn(app, @Switch_TrigIn_ZoomValueChanged, true);
            app.PlotControlsPanel_2 = uipanel(app.Tab2_Measure);
            app.PlotControlsPanel_2.Title = 'Plot Controls';
            app.Button_TrigOuts_ResetPlot = uibutton(app.PlotControlsPanel_2, 'push');
            app.Button_TrigOuts_ResetPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_TrigOuts_ResetPlotPushed, true);
            app.Button_TrigOuts_ResetPlot.Text = 'Reset View';
            app.PanSwitch_2Label_2 = uilabel(app.PlotControlsPanel_2);
            app.PanSwitch_2Label_2.Text = 'Pan';
            app.Switch_TrigOuts_Pan = uiswitch(app.PlotControlsPanel_2, 'slider');
            app.Switch_TrigOuts_Pan.ValueChangedFcn = createCallbackFcn(app, @Switch_TrigOuts_PanValueChanged, true);
            app.ZoomSwitch_2Label_2 = uilabel(app.PlotControlsPanel_2);
            app.ZoomSwitch_2Label_2.Text = 'Zoom';
            app.Switch_TrigOuts_Zoom = uiswitch(app.PlotControlsPanel_2, 'slider');
            app.Switch_TrigOuts_Zoom.ValueChangedFcn = createCallbackFcn(app, @Switch_TrigOuts_ZoomValueChanged, true);
            app.Axes_FreqMag = uiaxes(app.Tab2_Measure);
            title(app.Axes_FreqMag, 'Frequency response magnitude')
            xlabel(app.Axes_FreqMag, 'Frequency (Hz)')
            ylabel(app.Axes_FreqMag, 'Magnitude (dB)')
            app.Axes_FreqPhase = uiaxes(app.Tab2_Measure);
            title(app.Axes_FreqPhase, 'Frequency response phase')
            xlabel(app.Axes_FreqPhase, 'Frequency (Hz)')
            ylabel(app.Axes_FreqPhase, 'Phase (radians)')
            app.PlotControlsPanel_3 = uipanel(app.Tab2_Measure);
            app.PlotControlsPanel_3.Title = 'Plot Controls';
            app.Button_Mag_ResetPlot = uibutton(app.PlotControlsPanel_3, 'push');
            app.Button_Mag_ResetPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_Mag_ResetPlotPushed, true);
            app.Button_Mag_ResetPlot.Text = 'Reset View';
            app.PanSwitch_2Label_3 = uilabel(app.PlotControlsPanel_3);
            app.PanSwitch_2Label_3.Text = 'Pan';
            app.Switch_Mag_Pan = uiswitch(app.PlotControlsPanel_3, 'slider');
            app.Switch_Mag_Pan.ValueChangedFcn = createCallbackFcn(app, @Switch_Mag_PanValueChanged, true);
            app.ZoomSwitch_2Label_3 = uilabel(app.PlotControlsPanel_3);
            app.ZoomSwitch_2Label_3.Text = 'Zoom';
            app.Switch_Mag_Zoom = uiswitch(app.PlotControlsPanel_3, 'slider');
            app.Switch_Mag_Zoom.ValueChangedFcn = createCallbackFcn(app, @Switch_Mag_ZoomValueChanged, true);
            app.flowHzEditFieldLabel = uilabel(app.PlotControlsPanel_3);
            app.flowHzEditFieldLabel.Text = 'f (low, Hz)';
            app.axLim_Mag_fLow = uieditfield(app.PlotControlsPanel_3, 'numeric');
            app.axLim_Mag_fLow.ValueChangedFcn = createCallbackFcn(app, @axLim_Mag_fLowValueChanged, true);
            app.axLim_Mag_fLow.Value = 20;
            app.fhighHzEditFieldLabel = uilabel(app.PlotControlsPanel_3);
            app.fhighHzEditFieldLabel.Text = 'f (high, Hz)';
            app.axLim_Mag_fHigh = uieditfield(app.PlotControlsPanel_3, 'numeric');
            app.axLim_Mag_fHigh.ValueChangedFcn = createCallbackFcn(app, @axLim_Mag_fHighValueChanged, true);
            app.axLim_Mag_fHigh.Value = 4000;
            app.dBhighEditFieldLabel = uilabel(app.PlotControlsPanel_3);
            app.dBhighEditFieldLabel.Text = 'dB (high)';
            app.axLim_Mag_dBHigh = uieditfield(app.PlotControlsPanel_3, 'numeric');
            app.axLim_Mag_dBHigh.ValueChangedFcn = createCallbackFcn(app, @axLim_Mag_dBHighValueChanged, true);
            app.axLim_Mag_dBHigh.Value = 60;
            app.dBlowEditFieldLabel = uilabel(app.PlotControlsPanel_3);
            app.dBlowEditFieldLabel.Text = 'dB (low)';
            app.axLim_Mag_dBLow = uieditfield(app.PlotControlsPanel_3, 'numeric');
            app.axLim_Mag_dBLow.ValueChangedFcn = createCallbackFcn(app, @axLim_Mag_dBLowValueChanged, true);
            app.PlotControlsPanel_4 = uipanel(app.Tab2_Measure);
            app.PlotControlsPanel_4.Title = 'Plot Controls';
            app.Button_Phase_ResetPlot = uibutton(app.PlotControlsPanel_4, 'push');
            app.Button_Phase_ResetPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_Phase_ResetPlotPushed, true);
            app.Button_Phase_ResetPlot.Text = 'Reset View';
            app.PanSwitch_2Label_4 = uilabel(app.PlotControlsPanel_4);
            app.PanSwitch_2Label_4.Text = 'Pan';
            app.Switch_Phase_Pan = uiswitch(app.PlotControlsPanel_4, 'slider');
            app.Switch_Phase_Pan.ValueChangedFcn = createCallbackFcn(app, @Switch_Phase_PanValueChanged, true);
            app.ZoomSwitch_2Label_4 = uilabel(app.PlotControlsPanel_4);
            app.ZoomSwitch_2Label_4.Text = 'Zoom';
            app.Switch_Phase_Zoom = uiswitch(app.PlotControlsPanel_4, 'slider');
            app.Switch_Phase_Zoom.ValueChangedFcn = createCallbackFcn(app, @Switch_Phase_ZoomValueChanged, true);
            app.CapturedDataMangementPanel = uipanel(app.Tab2_Measure);
            app.CapturedDataMangementPanel.Title = 'Captured Data Mangement';
            app.Table_BufferControl = uitable(app.CapturedDataMangementPanel);
            app.Table_BufferControl.ColumnName = {'Buffer #'; 'Timestamp'; 'Include in averaging & data export?'};
            app.Table_BufferControl.RowName = {};
            app.Table_BufferControl.ColumnEditable = [false false true];
            app.Button_ClearAllBuffers = uibutton(app.CapturedDataMangementPanel, 'push');
            app.Button_ClearAllBuffers.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearAllBuffersPushed, true);
            app.Button_ClearAllBuffers.Text = 'Clear All Buffers';
            app.Button_ExportData = uibutton(app.CapturedDataMangementPanel, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Enable = 'off';
            app.Button_ExportData.Text = 'Export Selected Buffers';
            app.Button_ClearSelectedBuffers = uibutton(app.CapturedDataMangementPanel, 'push');
            app.Button_ClearSelectedBuffers.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearSelectedBuffersPushed, true);
            app.Button_ClearSelectedBuffers.Text = 'Clear Deselected Buffers';
            app.Panel_4 = uipanel(app.Tab2_Measure);
            app.Button_CaptureKeep = uibutton(app.Panel_4, 'push');
            app.Button_CaptureKeep.ButtonPushedFcn = createCallbackFcn(app, @Button_CaptureKeepPushed, true);
            app.Button_CaptureKeep.Enable = 'off';
            app.Button_CaptureKeep.Text = 'Keep Capture';
            app.Button_CaptureDiscard = uibutton(app.Panel_4, 'push');
            app.Button_CaptureDiscard.ButtonPushedFcn = createCallbackFcn(app, @Button_CaptureDiscardPushed, true);
            app.Button_CaptureDiscard.Enable = 'off';
            app.Button_CaptureDiscard.Text = 'Discard Capture';
            app.Axes_FreqCoherence = uiaxes(app.Tab2_Measure);
            title(app.Axes_FreqCoherence, 'Coherence')
            xlabel(app.Axes_FreqCoherence, 'Frequency (Hz)')
            ylabel(app.Axes_FreqCoherence, 'MSC')
            app.PlotDisplayControlPanel = uipanel(app.Tab2_Measure);
            app.PlotDisplayControlPanel.Title = 'Plot Display Control';
            app.Table_PlotSelect = uitable(app.PlotDisplayControlPanel);
            app.Table_PlotSelect.ColumnName = {'Response Channel ID'; 'Response Channel Name'; 'FRF Type'; 'Show Plot?'};
            app.Table_PlotSelect.RowName = {};
            app.Table_PlotSelect.ColumnEditable = [false false false true];
            app.Button_PlotLastCapture = uibutton(app.PlotDisplayControlPanel, 'push');
            app.Button_PlotLastCapture.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotLastCapturePushed, true);
            app.Button_PlotLastCapture.Enable = 'off';
            app.Button_PlotLastCapture.Text = 'Plot last capture only';
            app.Button_PlotEmsemble = uibutton(app.PlotDisplayControlPanel, 'push');
            app.Button_PlotEmsemble.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotEmsemblePushed, true);
            app.Button_PlotEmsemble.Enable = 'off';
            app.Button_PlotEmsemble.Text = 'Plot buffer average';
            app.Lamp_LastCaptureOnly = uilamp(app.PlotDisplayControlPanel);
            app.Lamp_LastCaptureOnly.Enable = 'off';
            app.Lamp_BufferAverage = uilamp(app.PlotDisplayControlPanel);
            app.Lamp_BufferAverage.Enable = 'off';
            app.CaptureclippedPanel = uipanel(app.Tab2_Measure);
            app.CaptureclippedPanel.Title = 'Capture clipped?';
            app.LampLabel = uilabel(app.CaptureclippedPanel);
            app.LampLabel.Enable = 'off';
            app.LampLabel.Text = '';
            app.Lamp_ClipWarning = uilamp(app.CaptureclippedPanel);
            app.Lamp_ClipWarning.Enable = 'off';
            app.TextArea_ClipWarning = uitextarea(app.CaptureclippedPanel);
            app.TextArea_ClipWarning.Editable = 'off';
            app.DropDown_LiveChan = uidropdown(app.Tab2_Measure);
            app.DropDown_LiveChan.Items = {'Chan " "'};
            app.DropDown_LiveChan.ValueChangedFcn = createCallbackFcn(app, @DropDown_LiveChanValueChanged, true);
            app.DropDown_LiveChan.Value = 'Chan " "';
            app.LivechanneldisplayLabel = uilabel(app.Tab2_Measure);
            app.LivechanneldisplayLabel.Text = 'Live channel display:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MikAdAM_V1a

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end